classdef Guiapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DisengageButton      matlab.ui.control.Button
        Joint6Slider_4       matlab.ui.control.Slider
        Joint6Slider_4Label  matlab.ui.control.Label
        Joint5Slider_4       matlab.ui.control.Slider
        Joint5Slider_4Label  matlab.ui.control.Label
        Joint4Slider_4       matlab.ui.control.Slider
        Joint4Slider_4Label  matlab.ui.control.Label
        Joint3Slider_4       matlab.ui.control.Slider
        Joint3Slider_4Label  matlab.ui.control.Label
        Joint2Slider_4       matlab.ui.control.Slider
        Joint2Slider_4Label  matlab.ui.control.Label
        Joint1Slider_4       matlab.ui.control.Slider
        Joint1Slider_4Label  matlab.ui.control.Label
        Joint6Slider_3       matlab.ui.control.Slider
        Joint6Slider_3Label  matlab.ui.control.Label
        Joint5Slider_3       matlab.ui.control.Slider
        Joint5Slider_3Label  matlab.ui.control.Label
        Joint4Slider_3       matlab.ui.control.Slider
        Joint4Slider_3Label  matlab.ui.control.Label
        Joint3Slider_3       matlab.ui.control.Slider
        Joint3Slider_3Label  matlab.ui.control.Label
        Joint2Slider_3       matlab.ui.control.Slider
        Joint2Slider_3Label  matlab.ui.control.Label
        Joint1Slider_3       matlab.ui.control.Slider
        Joint1Slider_3Label  matlab.ui.control.Label
        StartButton          matlab.ui.control.Button
        UR16eLabel           matlab.ui.control.Label
        UR3eLabel_2          matlab.ui.control.Label
        ResumeButton         matlab.ui.control.Button
        EstopButton          matlab.ui.control.Button
    end


    properties (Access = public)
        eStopFlag = 0;
        safetydisengage = 1;
        UR3eRobot = UR3e();
        UR16eRobot = UR16e();
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            disp('Start button clicked'); % Display message to confirm callback is working
            run('SpotlessMain.Run().m');
        end

        % Button pushed function: EstopButton
        function EstopButtonPushed(app, event)
            disp('e button clicked');
            app.eStopFlag = 1;
            app.safetydisengage = 0;
            
            
            % app.IsStopped = true;  % Set the stop flag to true
            % monitorEstop(true);    % Call function to monitor and stop
        end

        % Value changed function: Joint1Slider_3
        function Joint1Slider_3ValueChanged(app, event)
            angle = app.Joint1Slider_3.Value;
            currentQ = app.UR3eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(1) = deg2rad(angle);                        % Update joint 1 angle
            
            % cla(app.UIFigure.Children);

            app.UR3eRobot.model.plot(currentQ);        % Plot updated configuration for UR16e
            axis([-2 2.5 0 2.2 0 2]);  % Set axis limits for the workspace
            axis manual;               % Prevents automatic axis resizing
            % view(3);                   % Set a 3D view
            % axis equal; 
            % angle = app.Joint1Slider_3.Value;
            % prevQ = app.UR3eRobot.model.getpos();   % Get current UR16e joint configuration
            % currentQ = prevQ + deg2rad([0,0,0,0,0,angle]);                        % Update joint 1 angle
            % app.UR3eRobot.model.plot(currentQ);        % Plot updated configuration for UR16e
            
        end

        % Value changed function: Joint2Slider_3
        function Joint2Slider_3ValueChanged(app, event)
            angle = app.Joint2Slider_3.Value;
            currentQ = app.UR3eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(2) = deg2rad(angle);                        % Update joint 2 angle
            app.UR3eRobot.model.plot(currentQ);        % Plot updated configuration for UR16e
            
        end

        % Value changed function: Joint3Slider_3
        function Joint3Slider_3ValueChanged(app, event)
            angle = app.Joint3Slider_3.Value;
            currentQ = app.UR3eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(3) = deg2rad(angle);                        % Update joint 3 angle
            app.UR3eRobot.model.plot(currentQ);        % Plot updated configuration for UR16e
            
        end

        % Value changed function: Joint4Slider_3
        function Joint4Slider_3ValueChanged(app, event)
            angle = app.Joint4Slider_3.Value;
            currentQ = app.UR3eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(4) = deg2rad(angle);                        % Update joint 4 angle
            app.UR3eRobot.model.plot(currentQ);        % Plot updated configuration for UR16e
            
        end

        % Value changed function: Joint5Slider_3
        function Joint5Slider_3ValueChanged(app, event)
            angle = app.Joint5Slider_3.Value;
            currentQ = app.UR3eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(5) = deg2rad(angle);                      % Update joint 5 angle
            app.UR3eRobot.model.plot(currentQ);        % Plot updated configuration for UR16e
            
        end

        % Value changed function: Joint6Slider_3
        function Joint6Slider_3ValueChanged(app, event)
            angle = app.Joint6Slider_3.Value;
            currentQ = app.UR3eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(6) = deg2rad(angle);                      % Update joint 6 angle
            app.UR3eRobot.model.plot(currentQ);        % Plot updated configuration for UR16e
            
        end

        % Value changed function: Joint1Slider_4
        function Joint1Slider_4ValueChanged(app, event)
            angle = app.Joint1Slider_4.Value;
            currentQ = app.UR16eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(1) = deg2rad(angle);                        % Update joint 1 angle
            app.UR16eRobot.model.plot(currentQ);        % Plot updated configuration for UR3e
            
            
        end

        % Value changed function: Joint2Slider_4
        function Joint2Slider_4ValueChanged(app, event)
            angle = app.Joint2Slider_4.Value;
            currentQ = app.UR16eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(2) = deg2rad(angle);                        % Update joint 2 angle
            app.UR16eRobot.model.plot(currentQ);        % Plot updated configuration for UR3e
            
        end

        % Value changed function: Joint3Slider_4
        function Joint3Slider_4ValueChanged(app, event)
            angle = app.Joint3Slider_4.Value;
            currentQ = app.UR16eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(3) = deg2rad(angle);                      % Update joint 3 angle
            app.UR16eRobot.model.plot(currentQ);        % Plot updated configuration for UR3e
            
        end

        % Value changed function: Joint4Slider_4
        function Joint4Slider_4ValueChanged(app, event)
            angle = app.Joint4Slider_4.Value;
            currentQ = app.UR16eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(4) = deg2rad(angle);                       % Update joint 4 angle
            app.UR16eRobot.model.plot(currentQ);        % Plot updated configuration for UR3e
            
        end

        % Value changed function: Joint5Slider_4
        function Joint5Slider_4ValueChanged(app, event)
            angle = app.Joint5Slider_4.Value;
            currentQ = app.UR16eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(5) = deg2rad(angle);                         % Update joint 5 angle
            app.UR16eRobot.model.plot(currentQ);        % Plot updated configuration for UR3e
            
        end

        % Value changed function: Joint6Slider_4
        function Joint6Slider_4ValueChanged(app, event)
            angle = app.Joint6Slider_4.Value;
            currentQ = app.UR16eRobot.model.getpos();   % Get current UR16e joint configuration
            currentQ(6) = deg2rad(angle);                        % Update joint 6 angle
            app.UR16eRobot.model.plot(currentQ);        % Plot updated configuration for UR3e
            
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            if (app.safetydisengage == 1 && app.eStopFlag == 1)
            disp('resume button clicked');
            app.eStopFlag = 0;
            else
            disp(' The trajectory is resuming');
            end

        end

        % Button pushed function: DisengageButton
        function DisengageButtonPushed(app, event)
            disp('yellow button hit')

            if  (app.safetydisengage == 0 && app.eStopFlag == 1)
                app.safetydisengage = 1; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EstopButton = uibutton(app.UIFigure, 'push');
            app.EstopButton.ButtonPushedFcn = createCallbackFcn(app, @EstopButtonPushed, true);
            app.EstopButton.Text = 'E-stop';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.UR3eLabel_2 = uilabel(app.UIFigure);
            app.UR3eLabel_2.Text = 'UR3e';
            app.UR16eLabel = uilabel(app.UIFigure);
            app.UR16eLabel.Text = 'UR16e';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Joint1Slider_3Label = uilabel(app.UIFigure);
            app.Joint1Slider_3Label.Text = 'Joint 1';
            app.Joint1Slider_3 = uislider(app.UIFigure);
            app.Joint1Slider_3.Limits = [0 360];
            app.Joint1Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_3ValueChanged, true);
            app.Joint2Slider_3Label = uilabel(app.UIFigure);
            app.Joint2Slider_3Label.Text = 'Joint 2';
            app.Joint2Slider_3 = uislider(app.UIFigure);
            app.Joint2Slider_3.Limits = [0 360];
            app.Joint2Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_3ValueChanged, true);
            app.Joint3Slider_3Label = uilabel(app.UIFigure);
            app.Joint3Slider_3Label.Text = 'Joint 3';
            app.Joint3Slider_3 = uislider(app.UIFigure);
            app.Joint3Slider_3.Limits = [0 360];
            app.Joint3Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_3ValueChanged, true);
            app.Joint4Slider_3Label = uilabel(app.UIFigure);
            app.Joint4Slider_3Label.Text = 'Joint 4';
            app.Joint4Slider_3 = uislider(app.UIFigure);
            app.Joint4Slider_3.Limits = [0 360];
            app.Joint4Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_3ValueChanged, true);
            app.Joint5Slider_3Label = uilabel(app.UIFigure);
            app.Joint5Slider_3Label.Text = 'Joint 5';
            app.Joint5Slider_3 = uislider(app.UIFigure);
            app.Joint5Slider_3.Limits = [0 360];
            app.Joint5Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_3ValueChanged, true);
            app.Joint6Slider_3Label = uilabel(app.UIFigure);
            app.Joint6Slider_3Label.Text = 'Joint 6';
            app.Joint6Slider_3 = uislider(app.UIFigure);
            app.Joint6Slider_3.Limits = [0 360];
            app.Joint6Slider_3.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_3ValueChanged, true);
            app.Joint1Slider_4Label = uilabel(app.UIFigure);
            app.Joint1Slider_4Label.Text = 'Joint 1';
            app.Joint1Slider_4 = uislider(app.UIFigure);
            app.Joint1Slider_4.Limits = [0 360];
            app.Joint1Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_4ValueChanged, true);
            app.Joint2Slider_4Label = uilabel(app.UIFigure);
            app.Joint2Slider_4Label.Text = 'Joint 2';
            app.Joint2Slider_4 = uislider(app.UIFigure);
            app.Joint2Slider_4.Limits = [0 360];
            app.Joint2Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_4ValueChanged, true);
            app.Joint3Slider_4Label = uilabel(app.UIFigure);
            app.Joint3Slider_4Label.Text = 'Joint 3';
            app.Joint3Slider_4 = uislider(app.UIFigure);
            app.Joint3Slider_4.Limits = [0 360];
            app.Joint3Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_4ValueChanged, true);
            app.Joint4Slider_4Label = uilabel(app.UIFigure);
            app.Joint4Slider_4Label.Text = 'Joint 4';
            app.Joint4Slider_4 = uislider(app.UIFigure);
            app.Joint4Slider_4.Limits = [0 360];
            app.Joint4Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_4ValueChanged, true);
            app.Joint5Slider_4Label = uilabel(app.UIFigure);
            app.Joint5Slider_4Label.Text = 'Joint 5';
            app.Joint5Slider_4 = uislider(app.UIFigure);
            app.Joint5Slider_4.Limits = [0 360];
            app.Joint5Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_4ValueChanged, true);
            app.Joint6Slider_4Label = uilabel(app.UIFigure);
            app.Joint6Slider_4Label.Text = 'Joint 6';
            app.Joint6Slider_4 = uislider(app.UIFigure);
            app.Joint6Slider_4.Limits = [0 360];
            app.Joint6Slider_4.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_4ValueChanged, true);
            app.DisengageButton = uibutton(app.UIFigure, 'push');
            app.DisengageButton.ButtonPushedFcn = createCallbackFcn(app, @DisengageButtonPushed, true);
            app.DisengageButton.Text = 'Disengage';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Guiapp1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end